% description: Find objects (e.g. synapses) in three-dimentional image stacks.
% Copyright 2017-2018 Luca Della Santina (luca.dellasantina@gmail.com)
classdef ObjectFinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Lamp                   matlab.ui.control.Lamp
        UIAxes                 matlab.ui.control.UIAxes
        TabGroup               matlab.ui.container.TabGroup
        DetectTab              matlab.ui.container.Tab
        SelectFolderButton     matlab.ui.control.Button
        LoadImagesButton       matlab.ui.control.Button
        FindObjectsButton      matlab.ui.control.Button
        FilterButton           matlab.ui.control.Button
        Inspect3DButton        matlab.ui.control.Button
        CalcDensityButton      matlab.ui.control.Button
        PlotTab                matlab.ui.container.Tab
        DensitybydepthButton   matlab.ui.control.Button
        Locations2DButton      matlab.ui.control.Button
        Heatmap2DButton        matlab.ui.control.Button
        DensitylinearButton    matlab.ui.control.Button
        Skeleton2DButton       matlab.ui.control.Button
        SkeletonShollButton    matlab.ui.control.Button
        PlotNewWinCheckBox     matlab.ui.control.CheckBox
        ExportDataCheckBox     matlab.ui.control.CheckBox
        AboutTab               matlab.ui.container.Tab
        CopyrightLabel         matlab.ui.control.Label
        CopyrightLabel_3       matlab.ui.control.Label
        LucaDellaSantinaLabel  matlab.ui.control.Label
        Button                 matlab.ui.control.Button
        Version40Label         matlab.ui.control.Label
        httpsgithubcomlucadellasantinaObjectFinderLabel  matlab.ui.control.Label
        FolderField            matlab.ui.control.EditField
    end

%% ObjectFinder - Recognize 3D structures in image stacks
%  Copyright (C) 2016,2017,2018 Luca Della Santina
%
%  This program is free software: you can redistribute it and/or modify
%  it under the terms of the GNU General Public License as published by
%  the Free Software Foundation, either version 3 of the License, or
%  (at your option) any later version.
%
%  This program is distributed in the hope that it will be useful,
%  but WITHOUT ANY WARRANTY; without even the implied warranty of
%  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%  GNU General Public License for more details.
%
%  You should have received a copy of the GNU General Public License
%  along with this program.  If not, see <https://www.gnu.org/licenses/>.
%
    properties (Access = private)
        Settings
        Post
        Dend
        Mask
        Dots
        Filter
        Grouped
        Skel
        SkelFiner
        Density
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            colormap(app.UIAxes, gray(256));
            xticks(app.UIAxes, []);
            yticks(app.UIAxes, []);
            box(app.UIAxes, 'on');
            
            if ~isdir([pwd filesep 'I'])
                app.Lamp.Color = 'white';
                app.FolderField.Value = 'Select a valid working folder using the first button';
                app.LoadImagesButton.Enable = 'off';
                app.FindObjectsButton.Enable = 'off';
                app.FilterButton.Enable = 'off';
                app.Inspect3DButton.Enable = 'off';
                app.CalcDensityButton.Enable = 'off';
            else
                app.LoadImagesButton.Enable = 'on';
                app.Lamp.Color = 'green';
                app.FolderField.Value = pwd;
                app.Settings.TPN = [pwd filesep];
                if exist([pwd filesep 'Settings.mat'], 'file')
                    load([pwd filesep 'Settings.mat']);
                    app.Settings = Settings;
                    app.Settings.TPN = [pwd filesep];                    
                end
                if exist([pwd filesep 'Dots.mat'], 'file')
                    load([pwd filesep 'Dots.mat']);
                    app.Dots = Dots;
                    app.FindObjectsButton.Enable = 'on';
                    app.FilterButton.Enable = 'on';
                end
                if exist([pwd filesep 'Filter.mat'], 'file')
                    load([pwd filesep 'Filter.mat']);
                    app.Filter = Filter;
                    app.Inspect3DButton.Enable = 'on';
                end
                if exist([pwd filesep 'Grouped.mat'], 'file')
                    load([pwd filesep 'Dots.mat']);
                    app.Dots = Dots;
                    load([pwd filesep 'Grouped.mat']);
                    app.Grouped = Grouped;
                    app.CalcDensityButton.Enable = 'on';
                    app.DensitybydepthButton.Enable = 'on';
                else
                    app.CalcDensityButton.Enable = 'off';
                    app.DensitybydepthButton.Enable = 'off';
                end                    
                if exist([pwd filesep 'Skel.mat'], 'file')
                    load([pwd filesep 'Skel.mat']);
                    app.Skel = Skel;
                    app.Locations2DButton.Enable = 'on';
                    app.DensitylinearButton.Enable = 'on';
                    app.Heatmap2DButton.Enable = 'on';
                    app.Skeleton2DButton.Enable = 'on';
                    app.SkeletonShollButton.Enable = 'on';
                else
                    app.Locations2DButton.Enable = 'off';
                    app.DensitylinearButton.Enable = 'off';
                    app.Heatmap2DButton.Enable = 'off';
                    app.Skeleton2DButton.Enable = 'off';
                    app.SkeletonShollButton.Enable = 'off';
                end
            
            end
            
            % Clear plotting area
            cla(app.UIAxes)
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
            box(app.UIAxes, 'on');
        end

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
            app.Lamp.Color = 'yellow';
            cla(app.UIAxes);
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            box(app.UIAxes, 'on');            
            
            app.Settings = struct;
            app.Settings.TPN = [pwd filesep];
            if ~isdir([app.Settings.TPN 'data']), mkdir([app.Settings.TPN 'data']); end
            if ~isdir([app.Settings.TPN 'images']), mkdir([app.Settings.TPN 'images']); end
            
            % Read images to be used for analysis
            tmpDir=[app.Settings.TPN 'I' filesep];
            tmpFiles=dir([tmpDir '*.tif']);
            
            % Get dimensions of the first 3D TIF image (all other imagest must be same)
            tmpImInfo = imfinfo([tmpDir tmpFiles(1).name]);
            zs = numel(tmpImInfo); xs = tmpImInfo.Width; ys = tmpImInfo.Height;
            
            % Retrieve XY and Z resolution from TIFF image descriptor
            tmpXYres = num2str(1/tmpImInfo(1).XResolution);
            tmpZres = '0.3';% Default Z resolution if not specified in the TIF image
            if contains(tmpImInfo(1).ImageDescription, 'spacing=')
                tmpPos = strfind(tmpImInfo(1).ImageDescription,'spacing=');
                tmpZres = tmpImInfo(1).ImageDescription(tmpPos+8:end);
                tmpZres = regexp(tmpZres,'\n','split');
                tmpZres = tmpZres{1};
            end
            
            % Read all TIF images into a single Iraw matrix (X,Y,Z,Imge#)
            Iraw=zeros(ys, xs, zs, numel(tmpFiles), 'uint8');
            txtBar('Loading image stacks... ');
            for i = 1:numel(tmpFiles)
                for j = 1:zs
                    Iraw(:,:,j,i)=imread([tmpDir tmpFiles(i).name], j);
                    txtBar( 100*(j+i*zs-zs)/(zs*numel(tmpFiles)) );
                end
            end
            txtBar('DONE');
            
            Imax=squeeze(max(Iraw,[],3)); % Create a MIP of each image to display
            h=figure('units', 'normalized', 'position', [0.05 0.25 0.9 0.4]);
            for i = 1:size(Imax,3)
                subplot(1,size(Imax,3),i)
                image(Imax(:,:,i)*(500/double(max(max(Imax(:,:,i))))))
                title(['# ' num2str(i) ': ' tmpFiles(i).name]);
                set(gca,'box','off', 'YTickLabel',[],'XTickLabel',[]);
            end
            colormap gray(256);
            
            % Ask user for image idendity settings
            tmpPrompt = {'Objects image #:',...
                'Mask channel (0:no/use Mask.mat):',...
                'Neurites image # (0 = none):',...
                'xy resolution :',...
                'z resolution :',...
                'Debug mode (0:no, 1:yes):'};
            tmpAns = inputdlg(tmpPrompt, 'Assign channels', 1,...
                {'3', '0', '1',tmpXYres,tmpZres,'0'});
            
            app.Settings.ImInfo.xNumVox = xs;
            app.Settings.ImInfo.yNumVox = ys;
            app.Settings.ImInfo.zNumVox = zs;
            app.Settings.ImInfo.PostCh  = str2double(tmpAns(1));
            app.Settings.ImInfo.MaskCh  = str2double(tmpAns(2));
            app.Settings.ImInfo.DenCh   = str2double(tmpAns(3));
            app.Settings.ImInfo.xyum    = str2double(tmpAns(4));
            app.Settings.ImInfo.zum     = str2double(tmpAns(5));
            app.Settings.debug          = str2double(tmpAns(6));
            
            % Write Channels into matlab files
            if app.Settings.ImInfo.DenCh
                Dend = Iraw(:,:,:,app.Settings.ImInfo.DenCh);
                save([app.Settings.TPN 'Dend.mat'], 'Dend');
                app.Dend = Dend;
            end
            
            if app.Settings.ImInfo.PostCh
                Post=Iraw(:,:,:,app.Settings.ImInfo.PostCh);
                save([app.Settings.TPN 'Post.mat'], 'Post');
                app.Post = Post;
            end
            
            if app.Settings.ImInfo.MaskCh
                Mask = Iraw(:,:,:,app.Settings.ImInfo.MaskCh);
                Mask = Mask / max(max(max(Mask))); % Normalize mask max value to 1
                save([app.Settings.TPN 'Mask.mat'], 'Mask');
                saveastiff(app.Post, [app.Settings.TPN 'images' filesep 'PostMask.tif']); %save 3-D tiff image of the masked Post
                app.Mask = Mask;
            elseif exist([app.Settings.TPN 'Mask.mat'], 'file')
                disp('Loading Mask from Mask.mat');
                load([app.Settings.TPN 'Mask.mat']);
                app.Mask = Mask;
            else
                % Create dummy mask with all ones to process the entire image
                Mask = ones(size(app.Post), 'uint8');
                save([app.Settings.TPN 'Mask.mat'], 'Mask');
                app.Mask = Mask;
            end
            close all; clear i j Iraw Imax Is tmp* xs ys zs ans;
            
            tmpPrompt = {'x-y diameter of the biggest dot (um, default 1)',...
                'z diameter of the biggest dot (um, default 2)',...
                'x-y diameter of the smallest dot (um, default 0.25)',...
                'z diameter of the smallest dot (um, normally 0.5)',...
                'Intensity thresholds stepping (default 2)',...
                'Multi-peak correction factor (default 0)',...
                'Minimum iteration threshold (default 2)'};
            tmpAns = inputdlg(tmpPrompt, 'ObjectFinder settings', 1,...
                {'1','2','0.25','0.5','2','0','2'});
            
            % Calculate volume of the minimum / maximum dot sizes allowed
            % largest CtBP2 = elipsoid 1um-xy/2um-z diameter = ~330 voxels (.103x.103x0.3 um pixel size)
            MaxDotSize = (4/3*pi*(str2double(tmpAns(1))/2)*(str2double(tmpAns(1))/2)*(str2double(tmpAns(2))/2)) / (app.Settings.ImInfo.xyum*app.Settings.ImInfo.xyum*app.Settings.ImInfo.zum);
            MinDotSize = (4/3*pi*(str2double(tmpAns(3))/2)*(str2double(tmpAns(3))/2)*(str2double(tmpAns(4))/2)) / (app.Settings.ImInfo.xyum*app.Settings.ImInfo.xyum*app.Settings.ImInfo.zum);
            
            app.Settings.dotfinder.blockBuffer= round(str2double(tmpAns(1))/app.Settings.ImInfo.xyum);  % Overlapping buffer region between search block should be as big as the biggest dot we want to measure to make sure we are not missing any.
            %Settings.dotfinder.blockSize = 3* Settings.dotfinder.blockBuffer; % Use 3x blockBuffer for maximum speed during multi-threaded operations (best setting for speed)
            app.Settings.dotfinder.blockSize = 64; % Fixed this value to 64 otherwise computers with 16Gb RAM will run easily out of memory
            app.Settings.dotfinder.thresholdStep = str2double(tmpAns(5)); % step-size in the iterative search when looping through possible intensity values
            app.Settings.dotfinder.maxDotSize = MaxDotSize;       % max dot size exclusion criteria for single-peak dot DURING ITERATIVE THRESHOLDING, NOT FINAL.
            app.Settings.dotfinder.minDotSize= 3;                 % min dot size exclusion criteria DURING ITERATIVE THRESHOLDING, NOT FINAL.
            app.Settings.dotfinder.MultiPeakDotSizeCorrectionFactor = str2double(tmpAns(6)); % added by HO 2/8/2011, maxDotSize*MultiPeakDotSizeCorrectionFactor will be added for each additional dot joined to the previous dot, see dotfinder. With my PSD95CFP dots, super multipeak dots are rare, so put 0 for this factor.
            app.Settings.dotfinder.itMin = str2double(tmpAns(7)); % added by HO 2/9/2011 minimum iterative threshold allowed to be analyzed as voxels belonging to any dot...filter to remove value '1' pass thresholds. value '2' is also mostly noise for PSD95 dots, so 3 is the good starting point HO 2/9/2011
            app.Settings.dotfinder.peakCutoffLowerBound = 0.2;    % set threshold for all dots (0.2) after psychophysical testing with linescan and full 8-bit depth normalization HO 6/4/2010
            app.Settings.dotfinder.peakCutoffUpperBound = 0.2;    % set threshold for all dots (0.2) after psychophysical testing with linescan and full 8-bit depth normalization HO 6/4/2010
            app.Settings.dotfinder.minFinalDotITMax = 3;          % minimum ITMax allowed as FINAL dots. Any found dot whose ITMax is below this threshold value is removed from the registration into Dots. 5 will be the best for PSD95. HO 1/5/2010
            app.Settings.dotfinder.minFinalDotSize = MinDotSize;  % Minimum dot size allowed for FINAL dots.
            
            Settings = app.Settings;
            save([app.Settings.TPN 'Settings.mat'], 'Settings');
            
            app.FindObjectsButton.Enable = 'on';
            app.FilterButton.Enable = 'off';
            app.Inspect3DButton.Enable = 'off';
            app.CalcDensityButton.Enable = 'off';
            app.Lamp.Color = 'green';
            clear BlockBuffer MaxDotSize MinDotSize tmp* h;
        end

        % Button pushed function: FindObjectsButton
        function FindObjectsButtonPushed(app, event)
            app.Lamp.Color = 'yellow';
            % Seach objects inside the masked volume
            [app.Dots, app.Settings] = findObjects(app.Post.*app.Mask, app.Settings);
            % Create fields about sphericity of objects (Rounding)
            app.Dots = fitSphere(app.Dots, app.Settings.debug); 
            
            Dots= app.Dots;
            save([app.Settings.TPN 'Dots.mat'],'Dots');
            Settings = app.Settings;
            save([app.Settings.TPN 'Settings.mat'],'Settings');
            
            app.FilterButton.Enable = 'on';
            app.FindObjectsButton.Enable = 'off';
            app.Lamp.Color = 'green';
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            % Filter objects according to the following post-processing criteria (SG)
            app.Lamp.Color = 'yellow';
            tmpOptions.EdgeDotCut = 1;    % remove dots on edge of the expanded mask
            tmpOptions.SingleZDotCut = 1; % remove dots sitting on only one Z plane
            tmpOptions.xyStableDots = 0;  % remove objs whose centroid is not Z-stable
            app.Filter = filterObjects(app.Settings, app.Dots, tmpOptions);
            
            Filter = app.Filter;
            save([app.Settings.TPN 'Filter.mat'],'Filter');
            app.Grouped = groupFacingObjects(app.Settings, app.Dots, Filter);
            app.Grouped = fitSphere(app.Grouped, app.Settings.debug); 
            Grouped = app.Grouped;
            save([app.Settings.TPN 'Grouped.mat'],'Grouped');
            
            app.FilterButton.Enable = 'off';
            if isempty(dir([app.Settings.TPN 'I' filesep '*.ims']))
                app.Inspect3DButton.Enable = 'off';
            else
                app.Inspect3DButton.Enable = 'on';
            
            end
            app.CalcDensityButton.Enable = 'on';
            app.Lamp.Color = 'green';
        end

        % Button pushed function: Inspect3DButton
        function Inspect3DButtonPushed(app, event)
            app.Lamp.Color = 'yellow';
            load([pwd filesep 'Settings.mat']);
            app.Settings = Settings;
            exportObjectsToImaris(app.Settings, app.Dots, app.Filter); % Transfer objects to Imaris
            
            % Group objects that are facing each other and recalculate properties
            load([pwd filesep 'Filter.mat']);
            app.Filter = Filter;
            app.Grouped = groupFacingObjects(app.Settings, app.Dots, app.Filter);
            app.Grouped = fitSphere(app.Grouped, app.Settings.debug); 
            Grouped = app.Grouped;
            save([app.Settings.TPN 'Grouped.mat'],'Grouped');
            
            % If the cell has a skeleton proceed with these additional steps
            if exist([app.Settings.TPN 'Skel.mat'], 'file')
                load([app.Settings.TPN 'Skel.mat']);
                app.Skel = Skel;
                load([app.Settings.TPN 'Settings.mat']);
                app.Settings = Settings;
                app.Skel = calcSkelPathLength(app.Skel, false);
                Skel = app.Skel;
                save([app.Settings.TPN 'Skel.mat'],'Skel');
                app.Skel = generateFinerSkel(app.Skel, app.Settings.ImInfo.xyum, app.Settings.debug);
                app.Skel = calcSkelPathLength(app.Skel, false);
                Skel = app.Skel;
                save([app.Settings.TPN 'SkelFiner.mat'], 'Skel');
                app.Grouped = distDotsToCellBody(app.Grouped, app.Skel, app.Settings);
                app.Grouped = distDotsToSkel(app.Grouped, app.Skel, app.Settings);
                Grouped = app.Grouped;
                save([app.Settings.TPN 'Grouped.mat'],'Grouped');
                calcPathLengthStats(app.Settings, app.Grouped, app.Skel, false); % Plot distribution along dendrites
            end
            app.Lamp.Color = 'green';
        end

        % Button pushed function: CalcDensityButton
        function CalcDensityButtonPushed(app, event)
            app.Lamp.Color = 'yellow';
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            % If a skeleton is present then calculate properties of the individual cell
            if exist([app.Settings.TPN 'Skel.mat'], 'file')
                % Plot skeleton
                load([app.Settings.TPN 'SkelFiner.mat']);
                app.Density = calcDensity(app.Settings, app.Grouped, app.Skel, false); % Generate heatmaps of object density
                Density = app.Density;
                save([app.Settings.TPN 'Density.mat'],'Density');
            
                app.Heatmap2DButtonPushed;
            else
                % Compute and plot object distribution as function of volume depth
                app.Density = calcDotDensityAlongZ(app.Grouped, false);
                Density = app.Density;
                save([app.Settings.TPN 'Density'], 'Density');
                app.DensitybydepthButtonPushed;
            end
            app.Lamp.Color = 'green';
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            tmpDir = uigetdir;
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            if tmpDir
                cd(tmpDir);
                app.startupFcn;
            end                
        end

        % Button pushed function: Locations2DButton
        function Locations2DButtonPushed(app, event)
            load([app.Settings.TPN 'Skel.mat']);
            Skel.XYZ = Skel.FilStats.aXYZ;
            Skel.SomaPtID = Skel.FilStats.SomaPtID+1;
            Skel.FilStats.aEdges = Skel.FilStats.aEdges - Skel.FilStats.aEdges(1,1) + 1; % shift index edges so that first element starts with 1
            
            %% March edges to find matching pairs and recreate the branching pattern
            % For each branch first the march from cell body is computer, then
            % all points in common with previously calculated branches are removed
            % this leaves 1 segment per branch without any diplicate
            vNumberOfSpots = length(Skel.FilStats.aRad); % Store total number of points we need to iterate
            % Find position of terminal and biforcation points in the filament connectivity (aEdges)
            vNumberOfTerminals = 0;
            vTerminals = [];
            vNumberOfForks = 0;
            vForks = [];
            % Start investigating all spots except root (start from position #2 to exclude root)
            for vSpots = 2:vNumberOfSpots
                vEdge = find(Skel.FilStats.aEdges==vSpots);
                % if current edge is a terminal point of the skeleton, it should be listed once
                if length(vEdge)==1
                    % disp('found a terminal point');
                    vNumberOfTerminals = vNumberOfTerminals + 1;
                    vTerminals(vNumberOfTerminals) = vSpots;
                elseif length(vEdge)>2
                    % disp('found fork point');
                    vNumberOfForks = vNumberOfForks + 1;
                    vForks(vNumberOfForks) = vSpots;
                end
            end
                        
            % March backwards from each terminal point to the root in orher to find entire branch path
            vPaths=[]; % keeps an ongoing list of points already assigned to a branch
            for vTerminalIndex = 1:vNumberOfTerminals
                vLength = 1;
                vTerminal = vTerminals(vTerminalIndex); % start from terminal point
                vPath = vTerminal;  % add the terminal point to current path
                
                vFound = true;
                while vFound
                    % find among edges which one is connect to current terminal
                    % vEdge contains the number to wich vTerminal is connected
                    % vSide contains which side of the edge is vTerminal in this connection
                    [vEdge, vSide] = find(Skel.FilStats.aEdges==vTerminal);
                    
                    vFound = false;
                    for vNeighborIndex = 1:length(vEdge)
                        % looks like is marching both directions here
                        vNeighbor = Skel.FilStats.aEdges(vEdge(vNeighborIndex), 3-vSide(vNeighborIndex));
                        vNeighborSide = 3-vSide(vNeighborIndex); % Store side of the neighbor in the connectivity
                        if vNeighbor<vTerminal
                            vNewTerminal = vNeighbor;
                            vFound = true;
                        end
                    end
                    if vFound
                        vLength = vLength + 1;
                        vTerminal = vNewTerminal;
                        vPath(vLength) = vTerminal;
                    end
                end
                
                vPath = fliplr(vPath); % Flip path so that is not going terminal->root but root->terminal instead
                vPath = setdiff(vPath, vPaths); % Remove common part with the paths previously calculated
                vPaths = cat(2,vPaths, vPath); % Add current path to paths
                
                Skel.branches(vTerminalIndex).XYZ = Skel.FilStats.aXYZ(vPath,:);
                Skel.branches(vTerminalIndex).Rad = Skel.FilStats.aRad(vPath);
                %Skels(i).branches(vTerminalIndex).Edges = [1:vLength-1;2:vLength]';
                Skel.TotalBranches = numel(Skel.branches);
            end
            
            if app.PlotNewWinCheckBox.Value
                figure; hold on;
                for i=1:numel(Skel.branches)
                    plot(Skel.branches(i).XYZ(:,2)/app.Settings.ImInfo.xyum, Skel.branches(i).XYZ(:,1)/app.Settings.ImInfo.xyum, 'k-', 'LineWidth', 1.5);
                end
                scatter(Skel.FilStats.aXYZ(1,1)/app.Settings.ImInfo.xyum, Skel.FilStats.aXYZ(1,2)/app.Settings.ImInfo.xyum, 1000, 'o', 'LineWidth', 1.5, 'MarkerEdgeColor', [0.5 0 0.5]);
                xlim([0 app.Settings.ImInfo.xNumVox]);
                ylim([0 app.Settings.ImInfo.yNumVox]);
                box on;
                set(gca, 'XTick', [], 'YTick', []);
                pbaspect([1 1 1]);
            else
                cla(app.UIAxes);
                colorbar(app.UIAxes,'off');                
                hold(app.UIAxes, 'on');
                for i=1:numel(Skel.branches)
                    plot(app.UIAxes, Skel.branches(i).XYZ(:,2)/app.Settings.ImInfo.xyum, Skel.branches(i).XYZ(:,1)/app.Settings.ImInfo.xyum, 'k-', 'LineWidth', 1.5);
                end
                scatter(app.UIAxes, Skel.FilStats.aXYZ(1,1)/app.Settings.ImInfo.xyum, Skel.FilStats.aXYZ(1,2)/app.Settings.ImInfo.xyum, 1000, 'o', 'LineWidth', 1.5, 'MarkerEdgeColor', [0.5 0 0.5]);
                xlim(app.UIAxes, [0 app.Settings.ImInfo.xNumVox]);
                ylim(app.UIAxes, [0 app.Settings.ImInfo.yNumVox]);
                box(app.UIAxes, 'on');
                app.UIAxes.XTick= [];
                app.UIAxes.YTick= [];
            end            
            % Plot passing objects in UIAxes GUI component
            load([app.Settings.TPN 'Filter.mat']);
            app.Filter = Filter;            
            tmpPassingDots = find(app.Filter.passF);
            tmpA = zeros(numel(tmpPassingDots), 2);
            for i=1:numel(tmpPassingDots)
                tmpA(i,:)= mean(app.Dots.Vox(tmpPassingDots(i)).Pos(:,1:2));
            end
            if app.PlotNewWinCheckBox.Value
                scatter(tmpA(:,1), tmpA(:,2), 25, 'o', 'MarkerEdgeColor', [0 0.5 0.5]);
                xlim([0 app.Settings.ImInfo.xNumVox]);
                ylim([0 app.Settings.ImInfo.yNumVox]);
                box on;
                set(gca, 'XTick', [], 'YTick', []);                
            else
                scatter(app.UIAxes, tmpA(:,1), tmpA(:,2), 25, 'o', 'MarkerEdgeColor', [0 0.5 0.5]);
                xlim(app.UIAxes, [0 app.Settings.ImInfo.xNumVox]);
                ylim(app.UIAxes, [0 app.Settings.ImInfo.yNumVox]);
                box(app.UIAxes, 'on');
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                app.UIAxes.YDir = 'normal';
                app.UIAxes.YDir = 'normal';
                xlabel(app.UIAxes, '');
                ylabel(app.UIAxes, '');
                title(app.UIAxes, '');              
            end
        end

        % Button pushed function: Skeleton2DButton
        function Skeleton2DButtonPushed(app, event)
            load([app.Settings.TPN 'Skel.mat']);
            Skel.XYZ = Skel.FilStats.aXYZ;
            Skel.SomaPtID = Skel.FilStats.SomaPtID+1;
            Skel.FilStats.aEdges = Skel.FilStats.aEdges - Skel.FilStats.aEdges(1,1) + 1; % shift index edges so that first element starts with 1
            
            %% March edges to find matching pairs and recreate the branching pattern
            % For each branch first the march from cell body is computer, then
            % all points in common with previously calculated branches are removed
            % this leaves 1 segment per branch without any diplicate
            vNumberOfSpots = length(Skel.FilStats.aRad); % Store total number of points we need to iterate
            % Find position of terminal and biforcation points in the filament connectivity (aEdges)
            vNumberOfTerminals = 0;
            vTerminals = [];
            vNumberOfForks = 0;
            vForks = [];
            % Start investigating all spots except root (start from position #2 to exclude root)
            for vSpots = 2:vNumberOfSpots
                vEdge = find(Skel.FilStats.aEdges==vSpots);
                % if current edge is a terminal point of the skeleton, it should be listed once
                if length(vEdge)==1
                    % disp('found a terminal point');
                    vNumberOfTerminals = vNumberOfTerminals + 1;
                    vTerminals(vNumberOfTerminals) = vSpots;
                elseif length(vEdge)>2
                    % disp('found fork point');
                    vNumberOfForks = vNumberOfForks + 1;
                    vForks(vNumberOfForks) = vSpots;
                end
            end
                        
            % March backwards from each terminal point to the root in orher to find entire branch path
            vPaths=[]; % keeps an ongoing list of points already assigned to a branch
            for vTerminalIndex = 1:vNumberOfTerminals
                vLength = 1;
                vTerminal = vTerminals(vTerminalIndex); % start from terminal point
                vPath = vTerminal;  % add the terminal point to current path
                
                vFound = true;
                while vFound
                    % find among edges which one is connect to current terminal
                    % vEdge contains the number to wich vTerminal is connected
                    % vSide contains which side of the edge is vTerminal in this connection
                    [vEdge, vSide] = find(Skel.FilStats.aEdges==vTerminal);
                    
                    vFound = false;
                    for vNeighborIndex = 1:length(vEdge)
                        % looks like is marching both directions here
                        vNeighbor = Skel.FilStats.aEdges(vEdge(vNeighborIndex), 3-vSide(vNeighborIndex));
                        vNeighborSide = 3-vSide(vNeighborIndex); % Store side of the neighbor in the connectivity
                        if vNeighbor<vTerminal
                            vNewTerminal = vNeighbor;
                            vFound = true;
                        end
                    end
                    if vFound
                        vLength = vLength + 1;
                        vTerminal = vNewTerminal;
                        vPath(vLength) = vTerminal;
                    end
                end
                
                vPath = fliplr(vPath); % Flip path so that is not going terminal->root but root->terminal instead
                vPath = setdiff(vPath, vPaths); % Remove common part with the paths previously calculated
                vPaths = cat(2,vPaths, vPath); % Add current path to paths
                
                Skel.branches(vTerminalIndex).XYZ = Skel.FilStats.aXYZ(vPath,:);
                Skel.branches(vTerminalIndex).Rad = Skel.FilStats.aRad(vPath);
                %Skels(i).branches(vTerminalIndex).Edges = [1:vLength-1;2:vLength]';
                Skel.TotalBranches = numel(Skel.branches);
            end
            
            if app.PlotNewWinCheckBox.Value
                figure; hold on;
                for i=1:numel(Skel.branches)
                    plot(Skel.branches(i).XYZ(:,2)/app.Settings.ImInfo.xyum, Skel.branches(i).XYZ(:,1)/app.Settings.ImInfo.xyum, 'k-', 'LineWidth', 1.5);
                end
                scatter(Skel.FilStats.aXYZ(1,1)/app.Settings.ImInfo.xyum, Skel.FilStats.aXYZ(1,2)/app.Settings.ImInfo.xyum, 1000, 'o', 'LineWidth', 1.5, 'MarkerEdgeColor', [0.5 0 0.5]);
                xlim([1 app.Settings.ImInfo.xNumVox]);
                ylim([1 app.Settings.ImInfo.yNumVox]);
                box on;
                set(gca, 'XTick', [], 'YTick', []);
                pbaspect([1 1 1]);
            else
                cla(app.UIAxes);
                colorbar(app.UIAxes,'off');                
                hold(app.UIAxes, 'on');
                for i=1:numel(Skel.branches)
                    plot(app.UIAxes, Skel.branches(i).XYZ(:,2)/app.Settings.ImInfo.xyum, Skel.branches(i).XYZ(:,1)/app.Settings.ImInfo.xyum, 'k-', 'LineWidth', 1.5);
                end
                scatter(app.UIAxes, Skel.FilStats.aXYZ(1,1)/app.Settings.ImInfo.xyum, Skel.FilStats.aXYZ(1,2)/app.Settings.ImInfo.xyum, 1000, 'o', 'LineWidth', 1.5, 'MarkerEdgeColor', [0.5 0 0.5]);
                xlim(app.UIAxes, [1 app.Settings.ImInfo.xNumVox]);
                ylim(app.UIAxes, [1 app.Settings.ImInfo.yNumVox]);
                box(app.UIAxes, 'on');
                app.UIAxes.XTick= [];
                app.UIAxes.YTick= [];
                app.UIAxes.YDir = 'normal';
                app.UIAxes.YDir = 'normal';
                xlabel(app.UIAxes, '');
                ylabel(app.UIAxes, '');
                title(app.UIAxes, '');                            
            end
        end

        % Button pushed function: DensitybydepthButton
        function DensitybydepthButtonPushed(app, event)
            % Compute and plot object distribution as function of volume depth
            app.Density = calcDotDensityAlongZ(app.Grouped, false);
            Density = app.Density;
            save([app.Settings.TPN 'Density'], 'Density');
            % Plot object density along Z on UIAxes GUI component
            sizeBin = app.Grouped.ImInfo.xyum*app.Grouped.ImInfo.xNumVox...
                *app.Grouped.ImInfo.xyum*app.Grouped.ImInfo.yNumVox...
                *app.Grouped.ImInfo.zum*app.Density.binSize;
            tmpY = app.Density.densityPerc / sizeBin;
            tmpX = 1:100;
            if app.PlotNewWinCheckBox.Value
                plot(tmpX, tmpY, 'k', 'MarkerSize', 8);
                box off;
                xlim([0 100]);
                ylim([0 max(tmpY)]);
                set(gca, 'XTick', [0:10:100]);
                set(gca, 'YTickMode', 'auto');
                ylabel('Density (objects/µm^3)');
                xlabel('Volume depth percentage');                
            else
                cla(app.UIAxes);
                colorbar(app.UIAxes,'off');
                axis(app.UIAxes, 'on');
                plot(app.UIAxes, tmpX, tmpY, 'k', 'MarkerSize', 8);
                box(app.UIAxes, 'off');
                app.UIAxes.TickDir = 'out';
                xlim(app.UIAxes, [0 100]);
                ylim(app.UIAxes, [0 max(tmpY)]);
                app.UIAxes.XTickLabelMode = 'auto';
                app.UIAxes.XTickMode = 'auto';
                app.UIAxes.YTickLabelMode = 'auto';
                app.UIAxes.YTickMode = 'auto';
                box(app.UIAxes, 'off');
                app.UIAxes.YDir = 'normal';
                app.UIAxes.YDir = 'normal';
                title(app.UIAxes, '');
                ylabel(app.UIAxes, 'Density (objects/µm^3)');
                xlabel(app.UIAxes, 'Volume depth percentage');
            end
            if app.ExportDataCheckBox.Value
                if ~isdir([app.Settings.TPN 'results']), mkdir([app.Settings.TPN 'results']); end
                xlswrite([app.Settings.TPN 'results' filesep 'Density_by_depth.xls'],[tmpX;tmpY]');
            end
            
        end

        % Button pushed function: DensitylinearButton
        function DensitylinearButtonPushed(app, event)
            load([app.Settings.TPN 'SkelFiner.mat']);
            load([app.Settings.TPN 'Grouped.mat']);
            
            DotPathLengthList = Skel.FilStats.SkelPathLength2Soma(Grouped.ClosestSkelIDs);
            EdgePathLengthList = Skel.FilStats.EdgePathLength2Soma;
            EdgeLengthList = Skel.SegStats.Lengths;
            EdgePathLengthMax = ceil(max(EdgePathLengthList));
            
            DistBin = 10; %Bin distance is 10 micron by default
            DistFromSoma = 5:1:EdgePathLengthMax;
            clear NumDots EdgeLengths;
            for i=1:length(DistFromSoma)
                NumDots(i) = length(find((DotPathLengthList>DistFromSoma(i)-DistBin/2) & (DotPathLengthList<=DistFromSoma(i)+DistBin/2)));
                EdgeLengths(i) = sum(EdgeLengthList((EdgePathLengthList>DistFromSoma(i)-DistBin/2) & (EdgePathLengthList<=DistFromSoma(i)+DistBin/2)));
            end
            PoverD = NumDots./EdgeLengths;
            
            if app.PlotNewWinCheckBox.Value
                plot(DistFromSoma, PoverD, 'b', 'LineWidth', 2);
                xlim([0 EdgePathLengthMax]);
                ylim([0 max(1, max(PoverD))]);
                xlabel('Distance from cell body (µm), binning = 10 µm');
                ylabel('Linear density (puncta / µm dendrite)');            
            else
                cla(app.UIAxes);
                colorbar(app.UIAxes,'off');
                axis(app.UIAxes, 'on');
                plot(app.UIAxes, DistFromSoma, PoverD, 'b', 'LineWidth', 2);
                xlim(app.UIAxes, [0 EdgePathLengthMax]);
                ylim(app.UIAxes, [0 max(1, max(PoverD))]);
                app.UIAxes.YDir = 'normal';
                app.UIAxes.YDir = 'normal';
                title(app.UIAxes, '');              
                xlabel(app.UIAxes, 'Distance from cell body (µm), binning = 10 µm');
                ylabel(app.UIAxes, 'Linear density (puncta / µm dendrite)');                
            end    
            if app.ExportDataCheckBox.Value
                if ~isdir([app.Settings.TPN 'results']), mkdir([app.Settings.TPN 'results']); end
                xlswrite([app.Settings.TPN 'results' filesep 'Density_Linear.xls'],[DistFromSoma;PoverD]');
            end
        end

        % Button pushed function: SkeletonShollButton
        function SkeletonShollButtonPushed(app, event)
            load([app.Settings.TPN 'Skel.mat']);
            Skel.XYZ = Skel.FilStats.aXYZ;
            Skel.SomaPtID = Skel.FilStats.SomaPtID+1;
            Skel.FilStats.aEdges = Skel.FilStats.aEdges - Skel.FilStats.aEdges(1,1) + 1; % shift index edges so that first element starts with 1
            
            %% March edges to find matching pairs and recreate the branching pattern
            % For each branch first the march from cell body is computer, then
            % all points in common with previously calculated branches are removed
            % this leaves 1 segment per branch without any diplicate
            vNumberOfSpots = length(Skel.FilStats.aRad); % Store total number of points we need to iterate
            % Find position of terminal and biforcation points in the filament connectivity (aEdges)
            vNumberOfTerminals = 0;
            vTerminals = [];
            vNumberOfForks = 0;
            vForks = [];
            % Start investigating all spots except root (start from position #2 to exclude root)
            for vSpots = 2:vNumberOfSpots
                vEdge = find(Skel.FilStats.aEdges==vSpots);
                % if current edge is a terminal point of the skeleton, it should be listed once
                if length(vEdge)==1
                    % disp('found a terminal point');
                    vNumberOfTerminals = vNumberOfTerminals + 1;
                    vTerminals(vNumberOfTerminals) = vSpots;
                elseif length(vEdge)>2
                    % disp('found fork point');
                    vNumberOfForks = vNumberOfForks + 1;
                    vForks(vNumberOfForks) = vSpots;
                end
            end
                        
            % March backwards from each terminal point to the root in orher to find entire branch path
            vPaths=[]; % keeps an ongoing list of points already assigned to a branch
            for vTerminalIndex = 1:vNumberOfTerminals
                vLength = 1;
                vTerminal = vTerminals(vTerminalIndex); % start from terminal point
                vPath = vTerminal;  % add the terminal point to current path
                
                vFound = true;
                while vFound
                    % find among edges which one is connect to current terminal
                    % vEdge contains the number to wich vTerminal is connected
                    % vSide contains which side of the edge is vTerminal in this connection
                    [vEdge, vSide] = find(Skel.FilStats.aEdges==vTerminal);
                    
                    vFound = false;
                    for vNeighborIndex = 1:length(vEdge)
                        % looks like is marching both directions here
                        vNeighbor = Skel.FilStats.aEdges(vEdge(vNeighborIndex), 3-vSide(vNeighborIndex));
                        vNeighborSide = 3-vSide(vNeighborIndex); % Store side of the neighbor in the connectivity
                        if vNeighbor<vTerminal
                            vNewTerminal = vNeighbor;
                            vFound = true;
                        end
                    end
                    if vFound
                        vLength = vLength + 1;
                        vTerminal = vNewTerminal;
                        vPath(vLength) = vTerminal;
                    end
                end
                
                vPath = fliplr(vPath); % Flip path so that is not going terminal->root but root->terminal instead
                vPath = setdiff(vPath, vPaths); % Remove common part with the paths previously calculated
                vPaths = cat(2,vPaths, vPath); % Add current path to paths
                
                Skel.branches(vTerminalIndex).XYZ = Skel.FilStats.aXYZ(vPath,:);
                Skel.branches(vTerminalIndex).Rad = Skel.FilStats.aRad(vPath);
                %Skels(i).branches(vTerminalIndex).Edges = [1:vLength-1;2:vLength]';
                Skel.TotalBranches = numel(Skel.branches);
            end                        
            Skel.Sholl = shollAnalysis(Skel, 1, false);
            
            if app.PlotNewWinCheckBox.Value
                plot(Skel.Sholl.intersections(:,1), Skel.Sholl.intersections(:,2), 'b', 'LineWidth', 2);
                xlim([0 Skel.Sholl.intersections(end,1)]);
                ylim([0 max(Skel.Sholl.intersections(:,2))]);
                xlabel('Distance from cell body (µm)');
                ylabel('Sholl intersections');
            else
                cla(app.UIAxes);
                colorbar(app.UIAxes,'off');                
                plot(app.UIAxes, Skel.Sholl.intersections(:,1), Skel.Sholl.intersections(:,2), 'b', 'LineWidth', 2);
                xlim(app.UIAxes, [0 Skel.Sholl.intersections(end,1)]);
                ylim(app.UIAxes, [0 max(Skel.Sholl.intersections(:,2))]);
                app.UIAxes.XTickLabelMode = 'auto';
                app.UIAxes.XTickMode = 'auto';
                app.UIAxes.YTickLabelMode = 'auto';
                app.UIAxes.YTickMode = 'auto';
                box(app.UIAxes, 'off');
                app.UIAxes.YDir = 'normal';
                app.UIAxes.YDir = 'normal';
                title(app.UIAxes, '');
                xlabel(app.UIAxes, 'Distance from cell body (µm)');
                ylabel(app.UIAxes, 'Sholl intersections');
            end    
            if app.ExportDataCheckBox.Value
                if ~isdir([app.Settings.TPN 'results']), mkdir([app.Settings.TPN 'results']); end
                xlswrite([app.Settings.TPN 'results' filesep 'Skeleton_Sholl.xls'],Skel.Sholl.intersections);
            end
        end

        % Button pushed function: Heatmap2DButton
        function Heatmap2DButtonPushed(app, event)
            % Drap heatmap of linear density (P/D)
            app.Density = calcDensity(app.Settings, app.Grouped, app.Skel, false);
            if app.PlotNewWinCheckBox.Value  
                image(app.Density.DotDist ./ app.Density.DendDist*256);
                cmap=jet(256);
                cmap(1,:)=0;
                colormap(cmap);
                title('Linear density (objects/µm, blue=0 red=1)');
                colorbar('Ytick',[]);
                axis image; % adding color bar changes image x y dimention, so resize the image.
                axis off    % remove the axis
            else
                cla(app.UIAxes);
                axis(app.UIAxes, 'on');                
                image(app.UIAxes, app.Density.DotDist ./ app.Density.DendDist*256);
                cmap=jet(256);
                cmap(1,:)=0;
                colormap(app.UIAxes, cmap);
                xlim(app.UIAxes, [1 size(app.Density.DotDist,1)]);
                ylim(app.UIAxes, [1 size(app.Density.DotDist,2)]);
                title(app.UIAxes, 'Linear density (objects/µm, blue=0 red=1)');
                colorbar(app.UIAxes,'off'); 
                axis(app.UIAxes, 'off'); % remove the axis                
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'ObjectFinder';
            setAutoResize(app, app.UIFigure, true)
            app.Lamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DetectTab = uitab(app.TabGroup);
            app.DetectTab.Title = 'Detect';
            app.DetectTab.Units = 'pixels';
            app.SelectFolderButton = uibutton(app.DetectTab, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Icon = '48px-HomeFolder.png';
            app.SelectFolderButton.Text = '...';
            app.LoadImagesButton = uibutton(app.DetectTab, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Enable = 'off';
            app.LoadImagesButton.Icon = '48px-OpenImages.png';
            app.LoadImagesButton.Text = 'Load Images';
            app.FindObjectsButton = uibutton(app.DetectTab, 'push');
            app.FindObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @FindObjectsButtonPushed, true);
            app.FindObjectsButton.Enable = 'off';
            app.FindObjectsButton.Icon = '48px-FindObjects.png';
            app.FindObjectsButton.Text = 'Find Objects';
            app.FilterButton = uibutton(app.DetectTab, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Enable = 'off';
            app.FilterButton.Icon = '48px-Filter.png';
            app.FilterButton.Text = 'Filter';
            app.Inspect3DButton = uibutton(app.DetectTab, 'push');
            app.Inspect3DButton.ButtonPushedFcn = createCallbackFcn(app, @Inspect3DButtonPushed, true);
            app.Inspect3DButton.Enable = 'off';
            app.Inspect3DButton.Icon = '48px-Inspect.png';
            app.Inspect3DButton.Text = 'Inspect 3D';
            app.CalcDensityButton = uibutton(app.DetectTab, 'push');
            app.CalcDensityButton.ButtonPushedFcn = createCallbackFcn(app, @CalcDensityButtonPushed, true);
            app.CalcDensityButton.Enable = 'off';
            app.CalcDensityButton.Icon = '48px-CalcDensity.png';
            app.CalcDensityButton.Text = 'Calc Density';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.PlotTab.Units = 'pixels';
            app.DensitybydepthButton = uibutton(app.PlotTab, 'push');
            app.DensitybydepthButton.ButtonPushedFcn = createCallbackFcn(app, @DensitybydepthButtonPushed, true);
            app.DensitybydepthButton.Enable = 'off';
            app.DensitybydepthButton.Text = 'Density (by depth)';
            app.Locations2DButton = uibutton(app.PlotTab, 'push');
            app.Locations2DButton.ButtonPushedFcn = createCallbackFcn(app, @Locations2DButtonPushed, true);
            app.Locations2DButton.Enable = 'off';
            app.Locations2DButton.Text = 'Locations (2D)';
            app.Heatmap2DButton = uibutton(app.PlotTab, 'push');
            app.Heatmap2DButton.ButtonPushedFcn = createCallbackFcn(app, @Heatmap2DButtonPushed, true);
            app.Heatmap2DButton.Enable = 'off';
            app.Heatmap2DButton.Text = 'Heatmap (2D)';
            app.DensitylinearButton = uibutton(app.PlotTab, 'push');
            app.DensitylinearButton.ButtonPushedFcn = createCallbackFcn(app, @DensitylinearButtonPushed, true);
            app.DensitylinearButton.Enable = 'off';
            app.DensitylinearButton.Text = 'Density (linear)';
            app.Skeleton2DButton = uibutton(app.PlotTab, 'push');
            app.Skeleton2DButton.ButtonPushedFcn = createCallbackFcn(app, @Skeleton2DButtonPushed, true);
            app.Skeleton2DButton.Enable = 'off';
            app.Skeleton2DButton.Text = 'Skeleton (2D)';
            app.SkeletonShollButton = uibutton(app.PlotTab, 'push');
            app.SkeletonShollButton.ButtonPushedFcn = createCallbackFcn(app, @SkeletonShollButtonPushed, true);
            app.SkeletonShollButton.Enable = 'off';
            app.SkeletonShollButton.Text = 'Skeleton (Sholl)';
            app.PlotNewWinCheckBox = uicheckbox(app.PlotTab);
            app.PlotNewWinCheckBox.Text = 'Plot in new window';
            app.ExportDataCheckBox = uicheckbox(app.PlotTab);
            app.ExportDataCheckBox.Text = 'Export data in Excel';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.AboutTab.Units = 'pixels';
            app.CopyrightLabel = uilabel(app.AboutTab);
            app.CopyrightLabel.Text = 'ObjectFinder';
            app.CopyrightLabel_3 = uilabel(app.AboutTab);
            app.CopyrightLabel_3.Text = 'Licensed under the GNU GPL v3.';
            app.LucaDellaSantinaLabel = uilabel(app.AboutTab);
            app.LucaDellaSantinaLabel.Text = '© 2016-2018  Luca Della Santina';
            app.Button = uibutton(app.AboutTab, 'push');
            app.Button.Icon = 'app_icon_small.png';
            app.Button.Text = '';
            app.Version40Label = uilabel(app.AboutTab);
            app.Version40Label.Text = 'Version 4.0';
            app.httpsgithubcomlucadellasantinaObjectFinderLabel = uilabel(app.AboutTab);
            app.httpsgithubcomlucadellasantinaObjectFinderLabel.Text = 'https://github.com/lucadellasantina/ObjectFinder';
            app.FolderField = uieditfield(app.UIFigure, 'text');
            app.FolderField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ObjectFinder()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end